classdef fourierPeakInspector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        fImAxes                 matlab.ui.control.UIAxes
        imAxes                  matlab.ui.control.UIAxes
        peakTable               matlab.ui.control.Table
        upButton                matlab.ui.control.Button
        DoneSelectingEndButton  matlab.ui.control.Button
        downButton              matlab.ui.control.Button
        showpeakCheckBox        matlab.ui.control.CheckBox
        DoubleCheckBox          matlab.ui.control.CheckBox
        ConcernsCheckBox        matlab.ui.control.CheckBox
    end

% App used to inspect dominant frequencies in an image, and to mark a
% desired frequency

    properties (Access = public)
        freeToEnd = false;
        peakNow = 1; 
        drawings; 
        realspaceIm;
        RI;
        df_um;
    end
    
    properties (Access = private)
        highlightStyle = uistyle('BackgroundColor','red'); % formatting text rule
        
        pTablePriv % local table of peaks
    end
    
    methods (Access = public)
        
        function labelPeakNow(app) 
            
            %Update format of currently selected peak
            removeStyle(app.peakTable);
            addStyle(app.peakTable,app.highlightStyle,'row',app.peakNow);
            
            %Initialize Fourier space to plot the peak only
            fPeakOnly = zeros(size(app.realspaceIm,[1:2]));
            
            %Get dimensions of the fourier space
            [Nyx, centerPx, X, Y] = fourierMap(fPeakOnly);
            
            %option to double the period visualization, will be recorded in
            %output
            doDouble = get(app.DoubleCheckBox, 'Value');
            
            %current position of peak
            YYnow = round(app.pTablePriv.YY(app.peakNow)/(1+doDouble));
            XXnow = round(app.pTablePriv.XX(app.peakNow)/(1+doDouble));
            
            %clear mark on previously selected peak
            delete(app.drawings.pointPeakNow)
            
            %update mark to newly selected peak
            app.drawings.pointPeakNow = scatter(app.fImAxes,app.pTablePriv.XX(app.peakNow)*app.df_um(2),app.pTablePriv.YY(app.peakNow)*app.df_um(1),'r');
            
            %check if visualization of peak is turned on or off
            showPeak = get(app.showpeakCheckBox, 'Value');
            
            %plot current peak on the "only peak" fourier space
            fPeakOnly(centerPx(1)+YYnow,centerPx(2)+XXnow) = 1;
            fPeakOnly(centerPx(1)-YYnow,centerPx(2)-XXnow) = 1;
%            figure, imagesc(fPeakOnly) %for testing only

            %overlay selected frequency on image for visual comparison
            app.realspaceIm(:,:,1) = showPeak*mat2gray(real(ifft2(ifftshift(fPeakOnly))))*0.18;
            imshow(app.realspaceIm, app.RI, 'Parent',app.imAxes);
%            figure, imagesc(app.realspaceIm(:,:,1)) %for testing only
        end
        
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, im, yxzPxSize, fIm, pTable)
            
            %show input image (in RGB to allow for overlay later)
            app.realspaceIm = zeros([size(im,1),size(im,2),3]);
            app.realspaceIm(:,:,2) = mat2gray(im);
            app.realspaceIm(:,:,3) = mat2gray(im);

            
            %range of input peaks to examine 
            range = (1:40);
            app.pTablePriv = pTable(range,:);
            
            %show image and apply coordinates to axes
            [Nyx, centerPx, X, Y] = fourierMap(im);
            app.RI = imref2d(size(app.realspaceIm));
            app.RI.XWorldLimits = [0 Nyx(2)*yxzPxSize(2)]./1e-6;
            app.RI.YWorldLimits = [0 Nyx(1)*yxzPxSize(1)]./1e-6;
            imshow(app.realspaceIm, app.RI, 'Parent',app.imAxes);
            axis(app.imAxes, 'image');
            
            %create app version of peak table input
            app.peakTable.Data = table(pTable.rank(range),1./pTable.RR_um(range));
            
            %frequency sample
            fs_um = 1 ./ ([yxzPxSize(1), yxzPxSize(2)]/1e-6); %micron frequency
            app.df_um = fs_um./Nyx;
            
            
            %Calculate coordinates, show image and apply coordinates
            fImBounds = [-app.df_um.*ceil((Nyx-1)/2); (app.df_um.*(floor((Nyx-1)/2)) ) ];
            imagesc(app.fImAxes,fImBounds(:,2),fImBounds(:,1),fIm);
            %Scale axes and hold image on
            axis(app.fImAxes, 'image');
            hold(app.fImAxes,'on')
            
            %draw all peaks (limited to "range")
            scatter(app.fImAxes,pTable.XX(range)*app.df_um(2),pTable.YY(range)*app.df_um(1));
            %highligh the default selected peak, to be updated by user
            app.drawings.pointPeakNow = scatter(app.fImAxes,pTable.XX(app.peakNow)*app.df_um(2),pTable.YY(app.peakNow)*app.df_um(1),'r');
            
            labelPeakNow(app)

        end

        % Cell selection callback: peakTable
        function peakTableCellSelection(app, event)
            %cycle through table
            indices = event.Indices;
            app.peakNow = indices(1);
            
            labelPeakNow(app)
        end

        % Button pushed function: upButton
        function upButtonPushed(app, event)
            %cycle through table
            app.peakNow = app.peakNow - 1;
            app.peakNow = max(1,app.peakNow);

            labelPeakNow(app)
        end

        % Button pushed function: downButton
        function downButtonPushed(app, event)
            %cycle through table
            app.peakNow = app.peakNow + 1;
            app.peakNow = min(height(app.pTablePriv),app.peakNow);

            labelPeakNow(app)

        end

        % Button pushed function: DoneSelectingEndButton
        function DoneSelectingEndButtonPushed(app, event)
            %A "listener" for the calling script or function to wait until
            %this app is done being used
            app.freeToEnd = true;
            

        end

        % Value changed function: showpeakCheckBox
        function showpeakCheckBoxValueChanged(app, event)
            labelPeakNow(app)
        end

        % Value changed function: DoubleCheckBox
        function DoubleCheckBoxValueChanged(app, event)
            value = app.DoubleCheckBox.Value;
            labelPeakNow(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.fImAxes = uiaxes(app.UIFigure);
            title(app.fImAxes, 'Fourier transform and high-pass peaks')
            xlabel(app.fImAxes, '')
            ylabel(app.fImAxes, '')
            app.imAxes = uiaxes(app.UIFigure);
            title(app.imAxes, 'Masked image')
            xlabel(app.imAxes, '')
            ylabel(app.imAxes, '')
            app.peakTable = uitable(app.UIFigure);
            app.peakTable.ColumnName = {'rank'; 'period (um)'};
            app.peakTable.RowName = {};
            app.peakTable.CellSelectionCallback = createCallbackFcn(app, @peakTableCellSelection, true);
            app.upButton = uibutton(app.UIFigure, 'push');
            app.upButton.ButtonPushedFcn = createCallbackFcn(app, @upButtonPushed, true);
            app.upButton.Text = '↑';
            app.DoneSelectingEndButton = uibutton(app.UIFigure, 'push');
            app.DoneSelectingEndButton.ButtonPushedFcn = createCallbackFcn(app, @DoneSelectingEndButtonPushed, true);
            app.DoneSelectingEndButton.Text = 'Done Selecting, End';
            app.downButton = uibutton(app.UIFigure, 'push');
            app.downButton.ButtonPushedFcn = createCallbackFcn(app, @downButtonPushed, true);
            app.downButton.Text = '↓';
            app.showpeakCheckBox = uicheckbox(app.UIFigure);
            app.showpeakCheckBox.ValueChangedFcn = createCallbackFcn(app, @showpeakCheckBoxValueChanged, true);
            app.showpeakCheckBox.Text = 'show peak';
            app.showpeakCheckBox.Value = true;
            app.DoubleCheckBox = uicheckbox(app.UIFigure);
            app.DoubleCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoubleCheckBoxValueChanged, true);
            app.DoubleCheckBox.Text = 'Double';
            app.ConcernsCheckBox = uicheckbox(app.UIFigure);
            app.ConcernsCheckBox.Text = 'Concerns';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fourierPeakInspector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end